classdef IonicPolymerGloveGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DisplayTab                      matlab.ui.container.Tab
        BluetoothDongleCOMPortEditFieldLabel  matlab.ui.control.Label
        BluetoothDongleCOMPortEditField  matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        HandPlot                        matlab.ui.control.UIAxes
        ReferenceCapacitanceDataButton  matlab.ui.control.Button
        I1Label                         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        I2Label                         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        I3Label                         matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        M1Label                         matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        M3Label                         matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        R1Label                         matlab.ui.control.Label
        TextArea_6                      matlab.ui.control.TextArea
        R3Label                         matlab.ui.control.Label
        TextArea_7                      matlab.ui.control.TextArea
        S1Label                         matlab.ui.control.Label
        TextArea_8                      matlab.ui.control.TextArea
        S3Label                         matlab.ui.control.Label
        TextArea_9                      matlab.ui.control.TextArea
        T1Label                         matlab.ui.control.Label
        TextArea_10                     matlab.ui.control.TextArea
        T2Label                         matlab.ui.control.Label
        TextArea_11                     matlab.ui.control.TextArea
        P1Label                         matlab.ui.control.Label
        TextArea_12                     matlab.ui.control.TextArea
        P2Label                         matlab.ui.control.Label
        TextArea_13                     matlab.ui.control.TextArea
        P3Label                         matlab.ui.control.Label
        TextArea_14                     matlab.ui.control.TextArea
        P4Label                         matlab.ui.control.Label
        TextArea_15                     matlab.ui.control.TextArea
        P5Label                         matlab.ui.control.Label
        TextArea_16                     matlab.ui.control.TextArea
        PlotTab                         matlab.ui.container.Tab
        CapacitanceAxes                 matlab.ui.control.UIAxes
        GyroAxes                        matlab.ui.control.UIAxes
        AccelerationAxes                matlab.ui.control.UIAxes
        ReferenceSensorTab              matlab.ui.container.Tab
        ConnectRefButton                matlab.ui.control.Button
        RefSensorAxes                   matlab.ui.control.UIAxes
        DisconnectRefButton             matlab.ui.control.Button
        ZeroButton                      matlab.ui.control.Button
        ReadingLampLabel                matlab.ui.control.Label
        ReadingLamp                     matlab.ui.control.Lamp
        NotReadingLamp                  matlab.ui.control.Lamp
        CurrentValueEditFieldLabel      matlab.ui.control.Label
        CurrentValueEditField           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        UnitSwitch                      matlab.ui.control.Switch
        CalibrateTab                    matlab.ui.container.Tab
        SensorDropDownLabel             matlab.ui.control.Label
        SensorDropDown                  matlab.ui.control.DropDown
        CalibrateButton                 matlab.ui.control.Button
        RefGauge                        matlab.ui.control.LinearGauge
        UnitDropDown                    matlab.ui.control.DropDown
        InstructionsLabel               matlab.ui.control.Label
        InstructionsTextArea            matlab.ui.control.TextArea
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        MaxForceEditFieldLabel          matlab.ui.control.Label
        MaxForceEditField               matlab.ui.control.NumericEditField
        HandPlot_2                      matlab.ui.control.UIAxes
        Lamp_7Label                     matlab.ui.control.Label
        I1                              matlab.ui.control.Lamp
        Lamp_6Label                     matlab.ui.control.Label
        I2                              matlab.ui.control.Lamp
        Lamp_5Label                     matlab.ui.control.Label
        I3                              matlab.ui.control.Lamp
        Lamp_8Label                     matlab.ui.control.Label
        M1                              matlab.ui.control.Lamp
        Lamp_9Label                     matlab.ui.control.Label
        M3                              matlab.ui.control.Lamp
        Lamp_10Label                    matlab.ui.control.Label
        R1                              matlab.ui.control.Lamp
        Lamp_11Label                    matlab.ui.control.Label
        R3                              matlab.ui.control.Lamp
        Lamp_12Label                    matlab.ui.control.Label
        S1                              matlab.ui.control.Lamp
        Lamp_13Label                    matlab.ui.control.Label
        S3                              matlab.ui.control.Lamp
        Lamp_2Label                     matlab.ui.control.Label
        T1                              matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        T2                              matlab.ui.control.Lamp
        Lamp_3Label                     matlab.ui.control.Label
        P1                              matlab.ui.control.Lamp
        Lamp_4Label                     matlab.ui.control.Label
        P2                              matlab.ui.control.Lamp
        Lamp_14Label                    matlab.ui.control.Label
        P3                              matlab.ui.control.Lamp
        Lamp_15Label                    matlab.ui.control.Label
        P4                              matlab.ui.control.Lamp
        Lamp_16Label                    matlab.ui.control.Label
        P5                              matlab.ui.control.Lamp
        CurrentValueEditField_2         matlab.ui.control.NumericEditField
        CurrentValueEditField_2Label    matlab.ui.control.Label
        ResultsTextAreaLabel            matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        r2EditFieldLabel                matlab.ui.control.Label
        r2EditField                     matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        ResetCalibrationButton          matlab.ui.control.Button
        TestTab                         matlab.ui.container.Tab
        DoThingButton                   matlab.ui.control.Button
        SensorDropDown_2Label           matlab.ui.control.Label
        TestSensorDropDown              matlab.ui.control.DropDown
        CapAxes                         matlab.ui.control.UIAxes
        RefAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Serial Variables
        serialCOM
        
        % Timer callback variable
        dataCallbackTimer
        refSensorTimer
        calibTimer
        
        % Data Variables
        timeData = nan;
        cap1Data = nan;
        cap2Data = nan;
        cap3Data = nan;
        cap4Data = nan;
        cap5Data = nan;
        cap6Data = nan;
        cap7Data = nan;
        cap8Data = nan;
        cap9Data = nan;
        cap10Data = nan;
        cap11Data = nan;
        cap12Data = nan;
        cap13Data = nan;
        cap14Data = nan;
        cap15Data = nan;
        cap16Data = nan;
        accelXData = nan;
        accelYData = nan;
        accelZData = nan;
        gyroXData = nan;
        gyroYData = nan;
        gyroZData = nan;
        
        % Adjusted Variables
        timeAdjData = nan;
        cap1AdjData = nan;
        cap2AdjData = nan;
        cap3AdjData = nan;
        cap4AdjData = nan;
        cap5AdjData = nan;
        cap6AdjData = nan;
        cap7AdjData = nan;
        cap8AdjData = nan;
        cap9AdjData = nan;
        cap10AdjData = nan;
        cap11AdjData = nan;
        cap12AdjData = nan;
        cap13AdjData = nan;
        cap14AdjData = nan;
        cap15AdjData = nan;
        cap16AdjData = nan;
        accelXAdjData = nan;
        accelYAdjData = nan;
        accelZAdjData = nan;
        gyroXAdjData = nan;
        gyroYAdjData = nan;
        gyroZAdjData = nan;
        
        % Filtered Variables
        cap1FiltData = nan;
        cap2FiltData = nan;
        cap3FiltData = nan;
        cap4FiltData = nan;
        cap5FiltData = nan;
        cap6FiltData = nan;
        cap7FiltData = nan;
        cap8FiltData = nan;
        cap9FiltData = nan;
        cap10FiltData = nan;
        cap11FiltData = nan;
        cap12FiltData = nan;
        cap13FiltData = nan;
        cap14FiltData = nan;
        cap15FiltData = nan;
        cap16FiltData = nan;
        
        % Capcitance data References
        capRef = zeros(1,16);
        
        % Variables for plotting 
        plotLength = 10; % seconds
        
        % Variables for hand plot
        colourMap
        handBackground
        sensorMasks
        
        % Recording Data
        date = datetime('today');
        
        % Reference Sensor Variables
        BufferSize = 250;
        
        nReady = nan;
        
        totalData = nan;
        totalTime = nan;
        
        plotData = nan;
        
        data = nan;
        dataPtr =  nan;
        times = nan;
        timesPtr = nan;
        
        % Calibration Variables
        calibCap = nan;
        firstGoodValueCalibrating = true;
        
        tempBiasloadCellForCalibration = nan;
        tempBiasCapForCalibration = nan;
        
        TempCapVal = nan;
        LoadCellTempForce = nan;
        
        r2_deterCoeff = nan;
        tempSlope = nan;
        tempIntersec = nan;
        
        CapCalibrationValues = ones(16,2);
        
        Calibrating = false;
               
        % Filter Variables
        B = nan;
        A = nan;
        count4 = 0;
        x = 0;
    end

    methods (Access = public)

        
        function plotHand(app, sensorValues)
            sensorValues = round( sensorValues * 63 + 1 );

            displayHand = app.handBackground;
            
            for count = 1:16
                displayHand(:,:,1) = displayHand(:,:,1) + app.sensorMasks(:,:,1,count)*app.colourMap(sensorValues(count), 1);
                displayHand(:,:,2) = displayHand(:,:,2) + app.sensorMasks(:,:,2,count)*app.colourMap(sensorValues(count), 2);
                displayHand(:,:,3) = displayHand(:,:,3) + app.sensorMasks(:,:,3,count)*app.colourMap(sensorValues(count), 3);
            end
            
            image(app.HandPlot, displayHand);
            
        end
        
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Adjust the hand plot properties
            %app.HandPlot.XColor = 'none';
            %app.HandPlot.YColor = 'none';
            
            % Initialize variables for the hand plot
            
            app.colourMap = [0,         0,  143.4375; ...
                         0,         0,  159.3750; ...
                         0,         0,  175.3125; ...
                         0,         0,  191.2500; ...
                         0,         0,  207.1875; ...
                         0,         0,  223.1250; ...
                         0,         0,  239.0625; ...
                         0,         0,  255.0000; ...
                         0,   15.9375,  255.0000; ...
                         0,   31.8750,  255.0000; ...
                         0,   47.8125,  255.0000; ...
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                  127.5000,         0,         0; ];
        
            app.handBackground = imread('Hand Images/hand.png');
            image(app.HandPlot_2,app.handBackground);
         
            app.sensorMasks = uint8(zeros(size(app.handBackground,1), size(app.handBackground,2), 3, 16));
    
            app.sensorMasks(:,:,:,1) = imread('Hand Images/I1_mask.png');
            app.sensorMasks(:,:,:,2) = imread('Hand Images/I2_mask.png');
            app.sensorMasks(:,:,:,3) = imread('Hand Images/I3_mask.png');
            app.sensorMasks(:,:,:,4) = imread('Hand Images/M1_mask.png');
            app.sensorMasks(:,:,:,5) = imread('Hand Images/M3_mask.png');
            app.sensorMasks(:,:,:,6) = imread('Hand Images/R1_mask.png');
            app.sensorMasks(:,:,:,7) = imread('Hand Images/R3_mask.png');
            app.sensorMasks(:,:,:,8) = imread('Hand Images/S1_mask.png');
            app.sensorMasks(:,:,:,9) = imread('Hand Images/S3_mask.png');
            app.sensorMasks(:,:,:,10) = imread('Hand Images/T1_mask.png');
            app.sensorMasks(:,:,:,11) = imread('Hand Images/T2_mask.png');
            app.sensorMasks(:,:,:,12) = imread('Hand Images/P1_mask.png');
            app.sensorMasks(:,:,:,13) = imread('Hand Images/P2_mask.png');
            app.sensorMasks(:,:,:,14) = imread('Hand Images/P3_mask.png');
            app.sensorMasks(:,:,:,15) = imread('Hand Images/P4_mask.png');
            app.sensorMasks(:,:,:,16) = imread('Hand Images/P5_mask.png');
            
            for count = 1:16 
                app.handBackground(:,:,:) = app.handBackground(:,:,:) - app.sensorMasks(:,:,:,count);    
            end
    
            app.sensorMasks = app.sensorMasks/255;
            
            sValue = linspace(0,1,16);
            
            plotHand(app, sValue);
            
            % Initialize lowpass filter to be used on data
            order = 2;
            sampling_freq = 100;
            cut_off_freq = 10;
            passband_peak_to_peak_db = 0.5;
            stopband_attenuation = 20;
            [app.B,app.A] = ellip(order, passband_peak_to_peak_db, stopband_attenuation, cut_off_freq/(0.5*sampling_freq), 'low');
        
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if strcmp(app.ConnectButton.Enable, 'off') 
                if strcmp(app.ConnectRefButton.Enable, 'on')
                    app.ReadyLamp.Enable = 'off';
                    app.firstGoodValueCalibrating = true;
                
                    app.MaxForceEditField.Enable = 'off';
                    app.UnitDropDown.Enable = 'off';
                
                    app.RefGauge.Limits = [0 app.MaxForceEditField.Value];
                
                    stop(app.dataCallbackTimer)
            
                    loadRefLibrary();
            
                    app.Calibrating = true;
            
                    app.data = zeros((app.BufferSize),1);
                    app.dataPtr =  libpointer('singlePtr',app.data);
                    app.times = zeros(app.BufferSize,1);
                    app.timesPtr = libpointer('uint32Ptr',app.times);
            
                    app.calibTimer = timer( 'Period', 0.05,'TimerFcn', {@calibSensor, app}, 'ExecutionMode', 'fixedRate', 'BusyMode', 'queue');
                    start(app.calibTimer);
            
                    calibTimeout = tic;
            
                    while app.Calibrating
                        time = toc(calibTimeout);
                        if time > 20
                            disp('Calibration timed out. Stopping...');
                            break
                        end
                    end
            
                    stop(app.calibTimer);
            
                    app.ReadyLamp.Enable = 'off';
            
                    calllib('PPSRef','ppsStop');
            
                    if libisloaded('PPSRef')
                    unloadlibrary('PPSRef') 
                    end
                
                    start(app.dataCallbackTimer)
                
                    app.MaxForceEditField.Enable = 'on';
                    app.UnitDropDown.Enable = 'on';
                else
                    disp('ERROR: The reference sensor is connected in the Reference Sensor tab. Disconnect and then try again.')
                end
            else
                disp('ERROR: Glove is not connected');
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.BluetoothDongleCOMPortEditField.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            app.DisconnectButton.Enable = 'on';
            app.count4 = 0;
            
            % Initalize the data variables
            app.timeData = nan;
            app.cap1Data = nan;
            app.cap2Data = nan;
            app.cap3Data = nan;
            app.cap4Data = nan;
            app.cap5Data = nan;
            app.cap6Data = nan;
            app.cap7Data = nan;
            app.cap8Data = nan;
            app.cap9Data = nan;
            app.cap10Data = nan;
            app.cap11Data = nan;
            app.cap12Data = nan;
            app.cap13Data = nan;
            app.cap14Data = nan;
            app.cap15Data = nan;
            app.cap16Data = nan;
            app.accelXData = nan;
            app.accelYData = nan;
            app.accelZData = nan;
            app.gyroXData = nan;
            app.gyroYData = nan;
            app.gyroZData = nan;
            
            % Adjusted Variables
            app.timeAdjData = nan;
            app.cap1AdjData = nan;
            app.cap2AdjData = nan;
            app.cap3AdjData = nan;
            app.cap4AdjData = nan;
            app.cap5AdjData = nan;
            app.cap6AdjData = nan;
            app.cap7AdjData = nan;
            app.cap8AdjData = nan;
            app.cap9AdjData = nan;
            app.cap10AdjData = nan;
            app.cap11AdjData = nan;
            app.cap12AdjData = nan;
            app.cap13AdjData = nan;
            app.cap14AdjData = nan;
            app.cap15AdjData = nan;
            app.cap16AdjData = nan;
            app.accelXAdjData = nan;
            app.accelYAdjData = nan;
            app.accelZAdjData = nan;
            app.gyroXAdjData = nan;
            app.gyroYAdjData = nan;
            app.gyroZAdjData = nan;
            
            app.capRef = zeros(1,16);
            
            % Filtered Variables
            app.cap1FiltData = nan;
            app.cap2FiltData = nan;
            app.cap3FiltData = nan;
            app.cap4FiltData = nan;
            app.cap5FiltData = nan;
            app.cap6FiltData = nan;
            app.cap7FiltData = nan;
            app.cap8FiltData = nan;
            app.cap9FiltData = nan;
            app.cap10FiltData = nan;
            app.cap11FiltData = nan;
            app.cap12FiltData = nan;
            app.cap13FiltData = nan;
            app.cap14FiltData = nan;
            app.cap15FiltData = nan;
            app.cap16FiltData = nan;
            
            % Open the serial port with the bluetooth to serial to USB board
            disp(['Connecting to bluetooth dongle on :' app.BluetoothDongleCOMPortEditField.Value]);
                        
            % First close any current serial channels open in MATLAB
            com_objs = instrfindall; %Note: "instrfindall" will display all pertinent information about any open serial port
            if ~isempty(com_objs)
                fclose(com_objs); %close this serial COM port connection, but leave the serial object in existence
                delete(com_objs); %delete the serial object too
            end
        
            app.serialCOM = serial(app.BluetoothDongleCOMPortEditField.Value); % create a serial port object 
        
            % set serial parameters
            set(app.serialCOM, 'DataBits', 8);
            set(app.serialCOM, 'StopBits', 1);
            set(app.serialCOM, 'BaudRate', 115200);
            set(app.serialCOM, 'Parity', 'none');
            
            % Setup the serial port to generate an interrrupt when data is avaliable
            app.serialCOM.Terminator = 'z'; 
            app.serialCOM.BytesAvailableFcnMode = 'terminator';
            app.serialCOM.BytesAvailableFcn = {@dataCallback, app};
            
            fopen(app.serialCOM); % connect the serial port
            
            disp('If there were no errors then the connection was successful!');
            
            % Set up a periodic callback for plotting 
            app.dataCallbackTimer = timer( 'Period', 0.2,'TimerFcn', {@plotData, app}, 'ExecutionMode', 'fixedRate', 'BusyMode', 'queue');
            start(app.dataCallbackTimer)
            
            % start a stopwatch 
            tic;
            
        end

        % Button pushed function: ConnectRefButton
        function ConnectRefButtonPushed(app, event)
            app.ConnectRefButton.Enable = 'off';
            app.DisconnectRefButton.Enable = 'on';
            app.ZeroButton.Enable = 'on';
            app.UnitSwitch.Enable = 'off';
            
            loadRefLibrary();
            
            app.totalData = nan;
            app.totalTime = nan;
            
            app.data = zeros((app.BufferSize),1);
            app.dataPtr =  libpointer('singlePtr',app.data);
            app.times = zeros(app.BufferSize,1);
            app.timesPtr = libpointer('uint32Ptr',app.times);
            
            ReadInterval = 100;    %in ms
            
            app.refSensorTimer = timer( 'Period', (ReadInterval/1000),'TimerFcn', {@plotRefData, app}, 'ExecutionMode', 'fixedRate', 'BusyMode', 'queue');
            start(app.refSensorTimer)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.BluetoothDongleCOMPortEditField.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            
            fclose(app.serialCOM);
            delete(app.serialCOM);
            
            disp('The COM Port has been closed');
            
            stop(app.dataCallbackTimer)
            
            %             xlswrite('TempCapVal.xlsx',app.cap7AdjData);
            
        end

        % Button pushed function: DisconnectRefButton
        function DisconnectRefButtonPushed(app, event)
            stop(app.refSensorTimer)
                        
            app.ConnectRefButton.Enable = 'on';
            app.DisconnectRefButton.Enable = 'off';
            app.ZeroButton.Enable = 'off';
            app.ReadingLamp.Enable = 'off';
            app.NotReadingLamp.Enable = 'on';
            app.UnitSwitch.Enable = 'on';
            
            calllib('PPSRef','ppsStop');
            
            if libisloaded('PPSRef')
               unloadlibrary('PPSRef') 
            end
        end

        % Button pushed function: ReferenceCapacitanceDataButton
        function ReferenceCapacitanceDataButtonPushed(app, event)
             app.capRef(1) = mean(app.cap1Data(end-10:end));
             app.capRef(2) = mean(app.cap2Data(end-10:end));
             app.capRef(3) = mean(app.cap3Data(end-10:end));
             app.capRef(4) = mean(app.cap4Data(end-10:end));
             app.capRef(5) = mean(app.cap5Data(end-10:end));
             app.capRef(6) = mean(app.cap6Data(end-10:end));
             app.capRef(7) = mean(app.cap7Data(end-10:end));
             app.capRef(8) = mean(app.cap8Data(end-10:end));
             app.capRef(9) = mean(app.cap9Data(end-10:end));
             app.capRef(10) = mean(app.cap10Data(end-10:end));
             app.capRef(11) = mean(app.cap11Data(end-10:end));
             app.capRef(12) = mean(app.cap12Data(end-10:end));
             app.capRef(13) = mean(app.cap13Data(end-10:end));
             app.capRef(14) = mean(app.cap14Data(end-10:end));
             app.capRef(15) = mean(app.cap15Data(end-10:end));
             app.capRef(16) = mean(app.cap16Data(end-10:end));
        end

        % Button pushed function: ResetCalibrationButton
        function ResetCalibrationButtonPushed(app, event)
            app.CapCalibrationValues = ones(16,2);
            app.CapRegrChoice = zeros(16,1);
            
            app.I1.Enable = 'off';
            app.I2.Enable = 'off';
            app.I3.Enable = 'off';
            app.M1.Enable = 'off';
            app.M3.Enable = 'off';
            app.R1.Enable = 'off';
            app.R3.Enable = 'off';
            app.S1.Enable = 'off';
            app.S3.Enable = 'off';
            app.T1.Enable = 'off';
            app.T2.Enable = 'off';
            app.P1.Enable = 'off';
            app.P2.Enable = 'off';
            app.P3.Enable = 'off';
            app.P4.Enable = 'off';
            app.P5.Enable = 'off';
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Make all the variables local
            dateRecord = app.date;
            timeDataRecord = app.timeData;
            measVoltageDataRecord = app.measVoltageData;
            measCurrentDataRecord = app.measCurrentData;
            measResistanceDataRecord = app.measResistanceData;
            
            
            % Save the Data to a Matlab File
            save([char(app.FileNameEditField.Value) '.mat'], ...
                'dateRecord',...
                'timeDataRecord',...
                'measVoltageDataRecord',...
                'measCurrentDataRecord',...
                'measResistanceDataRecord');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            
            if libisloaded('PPSRef')
               calllib('PPSRef','ppsStop');
               unloadlibrary('PPSRef') 
            end
            
            com_objs = instrfindall; %Note: "instrfindall" will display all pertinent information about any open serial port
            if ~isempty(com_objs)
                fclose(com_objs); %close this serial COM port connection, but leave the serial object in existence
                delete(com_objs); %delete the serial object too
            end
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            calllib('PPSRef','ppsSetBaseline');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.BluetoothDongleCOMPortEditFieldLabel = uilabel(app.DisplayTab);
            app.BluetoothDongleCOMPortEditFieldLabel.Text = 'Bluetooth Dongle COM Port:';
            app.BluetoothDongleCOMPortEditField = uieditfield(app.DisplayTab, 'text');
            app.BluetoothDongleCOMPortEditField.Value = 'COM25';
            app.ConnectButton = uibutton(app.DisplayTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.DisplayTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.HandPlot = uiaxes(app.DisplayTab);
            app.HandPlot.XTick = [];
            app.HandPlot.YTick = [];
            app.ReferenceCapacitanceDataButton = uibutton(app.DisplayTab, 'push');
            app.ReferenceCapacitanceDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceCapacitanceDataButtonPushed, true);
            app.ReferenceCapacitanceDataButton.Text = 'Reference Capacitance Data';
            app.I1Label = uilabel(app.DisplayTab);
            app.I1Label.Text = '- I1';
            app.TextArea = uitextarea(app.DisplayTab);
            app.I2Label = uilabel(app.DisplayTab);
            app.I2Label.Text = '- I2';
            app.TextArea_2 = uitextarea(app.DisplayTab);
            app.I3Label = uilabel(app.DisplayTab);
            app.I3Label.Text = '- I3';
            app.TextArea_3 = uitextarea(app.DisplayTab);
            app.M1Label = uilabel(app.DisplayTab);
            app.M1Label.Text = '- M1';
            app.TextArea_4 = uitextarea(app.DisplayTab);
            app.M3Label = uilabel(app.DisplayTab);
            app.M3Label.Text = '- M3';
            app.TextArea_5 = uitextarea(app.DisplayTab);
            app.R1Label = uilabel(app.DisplayTab);
            app.R1Label.Text = '- R1';
            app.TextArea_6 = uitextarea(app.DisplayTab);
            app.R3Label = uilabel(app.DisplayTab);
            app.R3Label.Text = '- R3';
            app.TextArea_7 = uitextarea(app.DisplayTab);
            app.S1Label = uilabel(app.DisplayTab);
            app.S1Label.Text = '- S1';
            app.TextArea_8 = uitextarea(app.DisplayTab);
            app.S3Label = uilabel(app.DisplayTab);
            app.S3Label.Text = '- S3';
            app.TextArea_9 = uitextarea(app.DisplayTab);
            app.T1Label = uilabel(app.DisplayTab);
            app.T1Label.Text = '- T1';
            app.TextArea_10 = uitextarea(app.DisplayTab);
            app.T2Label = uilabel(app.DisplayTab);
            app.T2Label.Text = '- T2';
            app.TextArea_11 = uitextarea(app.DisplayTab);
            app.P1Label = uilabel(app.DisplayTab);
            app.P1Label.Text = '- P1';
            app.TextArea_12 = uitextarea(app.DisplayTab);
            app.P2Label = uilabel(app.DisplayTab);
            app.P2Label.Text = '- P2';
            app.TextArea_13 = uitextarea(app.DisplayTab);
            app.P3Label = uilabel(app.DisplayTab);
            app.P3Label.Text = '- P3';
            app.TextArea_14 = uitextarea(app.DisplayTab);
            app.P4Label = uilabel(app.DisplayTab);
            app.P4Label.Text = '- P4';
            app.TextArea_15 = uitextarea(app.DisplayTab);
            app.P5Label = uilabel(app.DisplayTab);
            app.P5Label.Text = '- P5';
            app.TextArea_16 = uitextarea(app.DisplayTab);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.CapacitanceAxes = uiaxes(app.PlotTab);
            xlabel(app.CapacitanceAxes, 'Time [s]')
            ylabel(app.CapacitanceAxes, 'Capacitance')
            app.GyroAxes = uiaxes(app.PlotTab);
            xlabel(app.GyroAxes, 'Time [s]')
            ylabel(app.GyroAxes, 'Gyro')
            app.AccelerationAxes = uiaxes(app.PlotTab);
            xlabel(app.AccelerationAxes, 'Time [s]')
            ylabel(app.AccelerationAxes, 'Acceleration')
            app.ReferenceSensorTab = uitab(app.TabGroup);
            app.ReferenceSensorTab.Title = 'Reference Sensor';
            app.ConnectRefButton = uibutton(app.ReferenceSensorTab, 'push');
            app.ConnectRefButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectRefButtonPushed, true);
            app.ConnectRefButton.Text = 'Connect Ref.';
            app.RefSensorAxes = uiaxes(app.ReferenceSensorTab);
            title(app.RefSensorAxes, 'Reference Sensor')
            xlabel(app.RefSensorAxes, 'Time (ms)')
            ylabel(app.RefSensorAxes, 'Force (lbf)')
            app.DisconnectRefButton = uibutton(app.ReferenceSensorTab, 'push');
            app.DisconnectRefButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRefButtonPushed, true);
            app.DisconnectRefButton.Enable = 'off';
            app.DisconnectRefButton.Text = 'Disconnect Ref.';
            app.ZeroButton = uibutton(app.ReferenceSensorTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Enable = 'off';
            app.ZeroButton.Text = 'Zero';
            app.ReadingLampLabel = uilabel(app.ReferenceSensorTab);
            app.ReadingLampLabel.Text = 'Reading';
            app.ReadingLamp = uilamp(app.ReferenceSensorTab);
            app.ReadingLamp.Enable = 'off';
            app.NotReadingLamp = uilamp(app.ReferenceSensorTab);
            app.CurrentValueEditFieldLabel = uilabel(app.ReferenceSensorTab);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.CurrentValueEditField = uieditfield(app.ReferenceSensorTab, 'numeric');
            app.CurrentValueEditField.Editable = 'off';
            app.Label = uilabel(app.ReferenceSensorTab);
            app.Label.Text = '';
            app.UnitSwitch = uiswitch(app.ReferenceSensorTab, 'slider');
            app.UnitSwitch.Items = {'Pound-Force', 'Newtons    '};
            app.UnitSwitch.Value = 'Newtons    ';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.SensorDropDownLabel = uilabel(app.CalibrateTab);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.CalibrateTab);
            app.SensorDropDown.Items = {'I1', 'I2', 'I3', 'M1', 'M3', 'R1', 'R3', 'S1', 'S3', 'T1', 'T2', 'P1', 'P2', 'P3', 'P4', 'P5'};
            app.SensorDropDown.Value = 'I1';
            app.CalibrateButton = uibutton(app.CalibrateTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.RefGauge = uigauge(app.CalibrateTab, 'linear');
            app.RefGauge.Limits = [0 10];
            app.RefGauge.MinorTicks = [];
            app.UnitDropDown = uidropdown(app.CalibrateTab);
            app.UnitDropDown.Items = {'lbf', 'N'};
            app.UnitDropDown.Value = 'N';
            app.InstructionsLabel = uilabel(app.CalibrateTab);
            app.InstructionsLabel.Text = 'Instructions:';
            app.InstructionsTextArea = uitextarea(app.CalibrateTab);
            app.InstructionsTextArea.Value = {' - Ensure the ''Reference Capacitance Data'' button in the Display tab has been pressed before calibration'; ''; ' - Select the sensor to calibrate from the drop-down'; ''; ' - Select a maximum calibration force for the sensor'; ''; '- Press the Calibrate button'; ''; ' - Press down on the reference sensor slowly and '; '  steadily (reach max force in about 3-4 sec.)'; '    - Ensure that the sensor is pressed down with '; '     linearly increasing force'};
            app.ReadyLampLabel = uilabel(app.CalibrateTab);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.CalibrateTab);
            app.ReadyLamp.Enable = 'off';
            app.MaxForceEditFieldLabel = uilabel(app.CalibrateTab);
            app.MaxForceEditFieldLabel.Text = 'Max Force';
            app.MaxForceEditField = uieditfield(app.CalibrateTab, 'numeric');
            app.MaxForceEditField.Value = 10;
            app.HandPlot_2 = uiaxes(app.CalibrateTab);
            app.HandPlot_2.XTick = [];
            app.HandPlot_2.YTick = [];
            app.Lamp_7Label = uilabel(app.CalibrateTab);
            app.Lamp_7Label.Enable = 'off';
            app.Lamp_7Label.Text = '';
            app.I1 = uilamp(app.CalibrateTab);
            app.I1.Enable = 'off';
            app.Lamp_6Label = uilabel(app.CalibrateTab);
            app.Lamp_6Label.Enable = 'off';
            app.Lamp_6Label.Text = '';
            app.I2 = uilamp(app.CalibrateTab);
            app.I2.Enable = 'off';
            app.Lamp_5Label = uilabel(app.CalibrateTab);
            app.Lamp_5Label.Enable = 'off';
            app.Lamp_5Label.Text = '';
            app.I3 = uilamp(app.CalibrateTab);
            app.I3.Enable = 'off';
            app.Lamp_8Label = uilabel(app.CalibrateTab);
            app.Lamp_8Label.Enable = 'off';
            app.Lamp_8Label.Text = '';
            app.M1 = uilamp(app.CalibrateTab);
            app.M1.Enable = 'off';
            app.Lamp_9Label = uilabel(app.CalibrateTab);
            app.Lamp_9Label.Enable = 'off';
            app.Lamp_9Label.Text = '';
            app.M3 = uilamp(app.CalibrateTab);
            app.M3.Enable = 'off';
            app.Lamp_10Label = uilabel(app.CalibrateTab);
            app.Lamp_10Label.Enable = 'off';
            app.Lamp_10Label.Text = '';
            app.R1 = uilamp(app.CalibrateTab);
            app.R1.Enable = 'off';
            app.Lamp_11Label = uilabel(app.CalibrateTab);
            app.Lamp_11Label.Enable = 'off';
            app.Lamp_11Label.Text = '';
            app.R3 = uilamp(app.CalibrateTab);
            app.R3.Enable = 'off';
            app.Lamp_12Label = uilabel(app.CalibrateTab);
            app.Lamp_12Label.Enable = 'off';
            app.Lamp_12Label.Text = '';
            app.S1 = uilamp(app.CalibrateTab);
            app.S1.Enable = 'off';
            app.Lamp_13Label = uilabel(app.CalibrateTab);
            app.Lamp_13Label.Enable = 'off';
            app.Lamp_13Label.Text = '';
            app.S3 = uilamp(app.CalibrateTab);
            app.S3.Enable = 'off';
            app.Lamp_2Label = uilabel(app.CalibrateTab);
            app.Lamp_2Label.Enable = 'off';
            app.Lamp_2Label.Text = '';
            app.T1 = uilamp(app.CalibrateTab);
            app.T1.Enable = 'off';
            app.LampLabel = uilabel(app.CalibrateTab);
            app.LampLabel.Enable = 'off';
            app.LampLabel.Text = '';
            app.T2 = uilamp(app.CalibrateTab);
            app.T2.Enable = 'off';
            app.Lamp_3Label = uilabel(app.CalibrateTab);
            app.Lamp_3Label.Enable = 'off';
            app.Lamp_3Label.Text = '';
            app.P1 = uilamp(app.CalibrateTab);
            app.P1.Enable = 'off';
            app.Lamp_4Label = uilabel(app.CalibrateTab);
            app.Lamp_4Label.Enable = 'off';
            app.Lamp_4Label.Text = '';
            app.P2 = uilamp(app.CalibrateTab);
            app.P2.Enable = 'off';
            app.Lamp_14Label = uilabel(app.CalibrateTab);
            app.Lamp_14Label.Enable = 'off';
            app.Lamp_14Label.Text = '';
            app.P3 = uilamp(app.CalibrateTab);
            app.P3.Enable = 'off';
            app.Lamp_15Label = uilabel(app.CalibrateTab);
            app.Lamp_15Label.Enable = 'off';
            app.Lamp_15Label.Text = '';
            app.P4 = uilamp(app.CalibrateTab);
            app.P4.Enable = 'off';
            app.Lamp_16Label = uilabel(app.CalibrateTab);
            app.Lamp_16Label.Enable = 'off';
            app.Lamp_16Label.Text = '';
            app.P5 = uilamp(app.CalibrateTab);
            app.P5.Enable = 'off';
            app.CurrentValueEditField_2 = uieditfield(app.CalibrateTab, 'numeric');
            app.CurrentValueEditField_2.Editable = 'off';
            app.CurrentValueEditField_2Label = uilabel(app.CalibrateTab);
            app.CurrentValueEditField_2Label.Text = 'Current Value';
            app.ResultsTextAreaLabel = uilabel(app.CalibrateTab);
            app.ResultsTextAreaLabel.Text = 'Results:';
            app.ResultsTextArea = uitextarea(app.CalibrateTab);
            app.ResultsTextArea.Editable = 'off';
            app.r2EditFieldLabel = uilabel(app.CalibrateTab);
            app.r2EditFieldLabel.Text = 'r2';
            app.r2EditField = uieditfield(app.CalibrateTab, 'numeric');
            app.r2EditField.Editable = 'off';
            app.SlopeEditFieldLabel = uilabel(app.CalibrateTab);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.CalibrateTab, 'numeric');
            app.SlopeEditField.Editable = 'off';
            app.SlopeEditField.Value = 1;
            app.ResetCalibrationButton = uibutton(app.CalibrateTab, 'push');
            app.ResetCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCalibrationButtonPushed, true);
            app.ResetCalibrationButton.Text = 'Reset Calibration';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.DoThingButton = uibutton(app.TestTab, 'push');
            app.DoThingButton.Text = 'Do Thing';
            app.SensorDropDown_2Label = uilabel(app.TestTab);
            app.SensorDropDown_2Label.Text = 'Sensor';
            app.TestSensorDropDown = uidropdown(app.TestTab);
            app.TestSensorDropDown.Items = {'I1', 'I3', 'M1', 'M3', 'R1', 'R3', 'S1', 'S3', 'T1', 'T2', 'P2', 'P3', 'P4', 'P5'};
            app.TestSensorDropDown.Value = 'I1';
            app.CapAxes = uiaxes(app.TestTab);
            xlabel(app.CapAxes, 'Time [s]')
            ylabel(app.CapAxes, 'Capacitance')
            app.RefAxes = uiaxes(app.TestTab);
            title(app.RefAxes, 'Reference Sensor')
            xlabel(app.RefAxes, 'Time (ms)')
            ylabel(app.RefAxes, 'Force (lbf)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = IonicPolymerGloveGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end